classdef CPSProject_App10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ItemsincartLabel   matlab.ui.control.Label
        Label4             matlab.ui.control.Label
        Label3             matlab.ui.control.Label
        Label2             matlab.ui.control.Label
        Label1             matlab.ui.control.Label
        Image5             matlab.ui.control.Image
        AddtocartButton_3  matlab.ui.control.Button
        AddtocartButton_2  matlab.ui.control.Button
        AddtocartButton    matlab.ui.control.Button
        closeappButton     matlab.ui.control.Button
        GoBackButton       matlab.ui.control.Button
        RefreshButton_3    matlab.ui.control.Button
        Image4             matlab.ui.control.Image
        OptionsLabel       matlab.ui.control.Label
        RefreshButton_2    matlab.ui.control.Button
        RefreshButton      matlab.ui.control.Button
        Image2             matlab.ui.control.Image
        Image              matlab.ui.control.Image
        Image3             matlab.ui.control.Image
    end


      properties (Access = public)
        Property % Description
       AccessoryDirectory
       AccessoryDirectory2
       AccessoryDirectory3
       JewelryCnt = 2;
       HairCnt = 2;
       ShoesCnt = 2;
       JewelryCnt2 = 2;
       BridesmaidShoppingCart3 = "";
        BridesmaidShoppingCart4 = "";
         BridesmaidShoppingCart5 = "";
       yes3 = 0;
       yes4 = 0;
       yes5 = 0;
      labelcnt = 0;
    end
    methods (Access = public, Static)
        function setAccessoryDirectory(app12, Jewelry,Hair,Shoes)
            app12.AccessoryDirectory = Jewelry;
            app12.AccessoryDirectory2 = Hair;
            app12.AccessoryDirectory3 = Shoes; 
             app12.Label4.Text = string(app12.labelcnt)
            app12.Image.ImageSource = "Accessories\" + app12.AccessoryDirectory(1).name

             y = split(app12.AccessoryDirectory(1).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app12.Label1.Text = "price: " + "$" +k

            app12.Image2.ImageSource = "Accessories\" + app12.AccessoryDirectory2(1).name

             y = split(app12.AccessoryDirectory2(1).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app12.Label2.Text = "price: " + "$" +k

            app12.Image4.ImageSource = "Accessories\" + app12.AccessoryDirectory3(1).name

             y = split(app12.AccessoryDirectory3(1).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app12.Label3.Text = "price: " + "$" +k
        end 
    end 


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
             if app.JewelryCnt < 10
          app.Image.ImageSource = "Accessories\"+ app.AccessoryDirectory(app.JewelryCnt).name
          
          y = split(app.AccessoryDirectory(app.JewelryCnt).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app.Label1.Text = "price: " + "$" +k

            app.JewelryCnt  = app.JewelryCnt  +1 

          end 
        end

        % Button pushed function: RefreshButton_2
        function RefreshButton_2Pushed(app, event)
             if app.HairCnt < 10
          app.Image2.ImageSource = "Accessories\"+ app.AccessoryDirectory2(app.HairCnt).name

          y = split(app.AccessoryDirectory2(app.HairCnt).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app.Label2.Text = "price: " + "$" +k

          app.HairCnt  = app.HairCnt  +1 
             end 
        end

        % Button pushed function: RefreshButton_3
        function RefreshButton_3Pushed(app, event)
            if app.ShoesCnt < 10
          app.Image4.ImageSource = "Accessories\"+ app.AccessoryDirectory3(app.ShoesCnt).name

          y = split(app.AccessoryDirectory3(app.ShoesCnt).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app.Label3.Text = "price: " + "$" +k

          app.ShoesCnt  = app.ShoesCnt  +1 
             end 
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            CPSProject_App9
            app.delete
        end

        % Button pushed function: closeappButton
        function closeappButtonPushed(app, event)
            app.delete
        end

        % Callback function
        function GoBackButton_2Pushed(app, event)
           app.JewelryCnt2 = app.JewelryCnt - 1;
            if app.JewelryCnt2 > 0
          app.Image.ImageSource = "Accessories\"+ app.AccessoryDirectory(app.JewelryCnt2).name
          app.JewelryCnt2  = app.JewelryCnt2  - 1; 
         

          end 
        end

        % Button pushed function: AddtocartButton
        function AddtocartButtonPushed(app, event)
            
            %app.yes3 = 1;
            
             a = dir('ShoppingCart\Jewelry\*.jpg')
          a = "ShoppingCart\Jewelry\" +  a(1).name
           delete(a)
          copyfile(app.Image.ImageSource, "ShoppingCart\Jewelry")


            app.labelcnt = app.labelcnt + 1;
             app.Label4.Text = string(app.labelcnt)
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
             %{
            if app.yes3 == 1
            CPSProject_App8.setBridesmaidShoppingCart3(CPSProject_App8, app.Image.ImageSource)
             
            app.delete

             end 
              if app.yes4 == 1
            CPSProject_App8.setBridesmaidShoppingCart4(CPSProject_App8, app.Image2.ImageSource)
             
            app.delete

              end 
               if app.yes5 == 1
            CPSProject_App8.setBridesmaidShoppingCart5(CPSProject_App8, app.Image4.ImageSource)
             
            app.delete

            end 
             %}

%{
            if app.yes3 == 1
               JewelryImage = app.Image.ImageSource
            end 
             if app.yes4 == 1
               HairImage = app.Image2.ImageSource
             end 
              if app.yes5 == 1
               ShoesImage = app.Image4.ImageSource
              end 

CPSProject_App8.setBridesmaidShoppingCart3(CPSProject_App8, JewelryImage, HairImage, ShoesImage)

app.delete

%}

            app.yes3 = 1; 

            CPSProject_App8.setyesfunction3(CPSProject_App8, app.yes3)
           
            app.delete
            

            app.yes4 = 1; 

            CPSProject_App8.setyesfunction4(CPSProject_App8, app.yes4)
           
            app.delete

            app.yes5 = 1; 

            CPSProject_App8.setyesfunction5(CPSProject_App8, app.yes5)
           
            app.delete


        end

        % Button pushed function: AddtocartButton_2
        function AddtocartButton_2Pushed(app, event)
            % app.yes4 = 1; 

              a2 = dir('ShoppingCart\Hair\*.jpg')
          a2 = "ShoppingCart\Hair\" +  a2(1).name
           delete(a2)
          copyfile(app.Image2.ImageSource, "ShoppingCart\Hair")


             app.labelcnt = app.labelcnt + 1;
             app.Label4.Text = string(app.labelcnt)
        end

        % Button pushed function: AddtocartButton_3
        function AddtocartButton_3Pushed(app, event)
            %app.yes5 = 1;

              a3 = dir('ShoppingCart\Shoes\*.jpg')
          a3 = "ShoppingCart\Shoes\" +  a3(1).name
           delete(a3)
          copyfile(app.Image4.ImageSource, "ShoppingCart\Shoes")


            app.labelcnt = app.labelcnt + 1;
             app.Label4.Text = string(app.labelcnt)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'BridesMaidOptions2.png');
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.RefreshButton_2 = uibutton(app.UIFigure, 'push');
            app.RefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_2Pushed, true);
            app.RefreshButton_2.Text = 'Refresh';
            app.OptionsLabel = uilabel(app.UIFigure);
            app.OptionsLabel.Text = 'Options';
            app.Image4 = uiimage(app.UIFigure);
            app.RefreshButton_3 = uibutton(app.UIFigure, 'push');
            app.RefreshButton_3.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_3Pushed, true);
            app.RefreshButton_3.Text = 'Refresh';
            app.GoBackButton = uibutton(app.UIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.closeappButton = uibutton(app.UIFigure, 'push');
            app.closeappButton.ButtonPushedFcn = createCallbackFcn(app, @closeappButtonPushed, true);
            app.closeappButton.Text = 'close app';
            app.AddtocartButton = uibutton(app.UIFigure, 'push');
            app.AddtocartButton.ButtonPushedFcn = createCallbackFcn(app, @AddtocartButtonPushed, true);
            app.AddtocartButton.Text = 'Add to cart';
            app.AddtocartButton_2 = uibutton(app.UIFigure, 'push');
            app.AddtocartButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddtocartButton_2Pushed, true);
            app.AddtocartButton_2.Text = 'Add to cart';
            app.AddtocartButton_3 = uibutton(app.UIFigure, 'push');
            app.AddtocartButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddtocartButton_3Pushed, true);
            app.AddtocartButton_3.Text = 'Add to cart';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'ShoppingCartIconNew.png');
            app.Label1 = uilabel(app.UIFigure);
            app.Label1.Text = 'Label1';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Label4';
            app.ItemsincartLabel = uilabel(app.UIFigure);
            app.ItemsincartLabel.Text = 'Items in cart:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CPSProject_App10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end