classdef CPSProject_App2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Button                  matlab.ui.control.Button
        ThemeDropDown           matlab.ui.control.DropDown
        ThemeDropDownLabel      matlab.ui.control.Label
        DressTypeDropDown       matlab.ui.control.DropDown
        DressTypeDropDownLabel  matlab.ui.control.Label
        WeddingDressLabel       matlab.ui.control.Label
        Image2                  matlab.ui.control.Image
    end

    
    properties (Access = public)
        Property % Description
        dressDirectory
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: DressTypeDropDown
        function DressTypeDropDownOpening(app, event)
            
        end

        % Clicked callback: DressTypeDropDown
        function DressTypeDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Clicked callback: ThemeDropDown
        function ThemeDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
         DressType = app.DressTypeDropDown.Value 
         Theme = app.ThemeDropDown.Value
         searchFile = "DressData\*_"+DressType + "_"+Theme + "*.jpg"
         %if DressType == "mermaid" && Color == "White" && Theme == "Glamorous"
             %app.dressDirectory = dir('*_Mermaid_Glamorous_white*.jpg')
              app.dressDirectory = dir(searchFile)
             
             %CPSProject_App3
             CPSProject_App3.setDressDirectory(CPSProject_App3, app.dressDirectory)
             
            app.delete
         %end 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'whiteBlankFlower.png';
            app.WeddingDressLabel = uilabel(app.UIFigure);
            app.WeddingDressLabel.Text = ' Wedding Dress';
            app.DressTypeDropDownLabel = uilabel(app.UIFigure);
            app.DressTypeDropDownLabel.Text = 'Dress Type';
            app.DressTypeDropDown = uidropdown(app.UIFigure);
            app.DressTypeDropDown.Items = {'Mermaid', 'Ballgown', 'A-line', 'Trumpet', 'Slip'};
            app.DressTypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DressTypeDropDownOpening, true);
            app.DressTypeDropDown.ClickedFcn = createCallbackFcn(app, @DressTypeDropDownClicked, true);
            app.DressTypeDropDown.Value = 'Mermaid';
            app.ThemeDropDownLabel = uilabel(app.UIFigure);
            app.ThemeDropDownLabel.Text = 'Theme';
            app.ThemeDropDown = uidropdown(app.UIFigure);
            app.ThemeDropDown.Items = {'Traditional', 'Bohemian', 'Glamorous', 'Vintage', 'Fairytale'};
            app.ThemeDropDown.ClickedFcn = createCallbackFcn(app, @ThemeDropDownClicked, true);
            app.ThemeDropDown.Value = 'Traditional';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CPSProject_App2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end