classdef CPSProject_App3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BacktomainButton      matlab.ui.control.Button
        BacktoOptionsButton   matlab.ui.control.Button
        Option2Label          matlab.ui.control.Label
        RefreshOptionsButton  matlab.ui.control.Button
        SelectButton_3        matlab.ui.control.Button
        SelectButton_2        matlab.ui.control.Button
        SelectButton          matlab.ui.control.Button
        Option3Label          matlab.ui.control.Label
        PriceLabel            matlab.ui.control.Label
        Image2_3              matlab.ui.control.Image
        Image2_2              matlab.ui.control.Image
        Image2                matlab.ui.control.Image
        DressOptionsLabel     matlab.ui.control.Label
        Image                 matlab.ui.control.Image
    end

    
    properties (Access = public)
        dressDirectory
        cnt = 4;
        ColorDressDirectory
         % Description
    end
    
    methods (Access = public, Static)
        
        function setDressDirectory(app, parameterDress)
           
            
           app.dressDirectory = parameterDress;
          
          app.Image2.ImageSource = "DressData\"+ app.dressDirectory(1).name

            y = split(app.dressDirectory(1).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app.PriceLabel.Text = "price: " + "$" +k

          app.Image2_2.ImageSource = "DressData\"+app.dressDirectory(2).name

            y = split(app.dressDirectory(2).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app.Option2Label.Text = "price: " + "$" +k


          app.Image2_3.ImageSource = "DressData\"+app.dressDirectory(3).name

           y = split(app.dressDirectory(3).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app.Option3Label.Text = "price: " + "$" +k 

        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            disp("App3")
            disp(app.dressDirectory)
            %app.Image2.ImageSource = app.dressDirectory
        end

        % Button pushed function: RefreshOptionsButton
        function RefreshOptionsButtonPushed(app, event)


           %app.dressDirectory = parameterDress;
          if app.cnt < 10
          app.Image2.ImageSource = "DressData\"+ app.dressDirectory(app.cnt).name

          y = split(app.dressDirectory(app.cnt).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app.PriceLabel.Text = "price: " + "$" +k 

          app.cnt = app.cnt +1 
          app.Image2_2.ImageSource = "DressData\"+app.dressDirectory(app.cnt).name

           y = split(app.dressDirectory(app.cnt).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app.Option2Label.Text = "price: " + "$" +k 

          app.cnt = app.cnt +1 
          app.Image2_3.ImageSource = "DressData\"+app.dressDirectory(app.cnt).name

           y = split(app.dressDirectory(app.cnt).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app.Option3Label.Text = "price: " + "$" +k 

          app.cnt = app.cnt +1 

          end 


        end

        % Button pushed function: BacktoOptionsButton
        function BacktoOptionsButtonPushed(app, event)
            CPSProject_App2
            app.delete
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            
            display("hello")
            CPSProject_App4.setColorDressDirectory(CPSProject_App4,app.Image2.ImageSource)
            app.delete
            
           
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            CPSProject_App4.setColorDressDirectory(CPSProject_App4,app.Image2_2.ImageSource)
            app.delete
        end

        % Button pushed function: SelectButton_3
        function SelectButton_3Pushed(app, event)
            CPSProject_App4.setColorDressDirectory(CPSProject_App4,app.Image2_3.ImageSource)
            app.delete
        end

        % Button pushed function: BacktomainButton
        function BacktomainButtonPushed(app, event)
            CPSProject_App1_2
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Green White Minimalist Floral Quotes Motivation Instagram Post.png');
            app.DressOptionsLabel = uilabel(app.UIFigure);
            app.DressOptionsLabel.Text = 'Dress Options';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_3 = uiimage(app.UIFigure);
            app.PriceLabel = uilabel(app.UIFigure);
            app.PriceLabel.Text = 'Price:';
            app.Option3Label = uilabel(app.UIFigure);
            app.Option3Label.Text = 'Option #3';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SelectButton_2 = uibutton(app.UIFigure, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.SelectButton_3 = uibutton(app.UIFigure, 'push');
            app.SelectButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_3Pushed, true);
            app.SelectButton_3.Text = 'Select';
            app.RefreshOptionsButton = uibutton(app.UIFigure, 'push');
            app.RefreshOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshOptionsButtonPushed, true);
            app.RefreshOptionsButton.Text = 'Refresh Options';
            app.Option2Label = uilabel(app.UIFigure);
            app.Option2Label.Text = 'Option #2';
            app.BacktoOptionsButton = uibutton(app.UIFigure, 'push');
            app.BacktoOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoOptionsButtonPushed, true);
            app.BacktoOptionsButton.Text = 'Back to Options';
            app.BacktomainButton = uibutton(app.UIFigure, 'push');
            app.BacktomainButton.ButtonPushedFcn = createCallbackFcn(app, @BacktomainButtonPushed, true);
            app.BacktomainButton.Text = 'Back to main';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CPSProject_App3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end