classdef CPSProject_App5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Button                   matlab.ui.control.Button
        DressStyleDropDown       matlab.ui.control.DropDown
        DressStyleDropDownLabel  matlab.ui.control.Label
        BridesmaidsLabel         matlab.ui.control.Label
        Image                    matlab.ui.control.Image
    end

    
    properties (Access = public)
        Property % Description
        bridesmaidDirectory
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            BridesmaidType = app.DressStyleDropDown.Value;
         
         searchFile = "Bridesmaid\*_"+BridesmaidType+ "*.jpg"
         %if DressType == "mermaid" && Color == "White" && Theme == "Glamorous"
             %app.dressDirectory = dir('*_Mermaid_Glamorous_white*.jpg')
              app.bridesmaidDirectory = dir(searchFile)
             
             %CPSProject_App3
             CPSProject_App6.setbridesmaidDirectory(CPSProject_App6, app.bridesmaidDirectory)
             
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BridesMaidOptions.jpg');
            app.BridesmaidsLabel = uilabel(app.UIFigure);
            app.BridesmaidsLabel.Text = 'Bridesmaids';
            app.DressStyleDropDownLabel = uilabel(app.UIFigure);
            app.DressStyleDropDownLabel.Text = 'Dress Style';
            app.DressStyleDropDown = uidropdown(app.UIFigure);
            app.DressStyleDropDown.Items = {'A-line', 'Jumpsuit', 'Sheath', 'Trumpet', 'Seperates'};
            app.DressStyleDropDown.Value = 'A-line';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CPSProject_App5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end