classdef CPSProject_App6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label3                  matlab.ui.control.Label
        Label2                  matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        RefreshOptionsButton    matlab.ui.control.Button
        SelectButton_3          matlab.ui.control.Button
        SelectButton_2          matlab.ui.control.Button
        SelectButton            matlab.ui.control.Button
        Image2_3                matlab.ui.control.Image
        Image2_2                matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        BridesmaidOptionsLabel  matlab.ui.control.Label
        Image                   matlab.ui.control.Image
    end

    
    properties (Access = public)
        Property % Description
        bridesmaidDirectory
        cnt3 = 4;
        ColorDressDirectory2
    end
    methods (Access = public, Static)
        function setbridesmaidDirectory(app3, parameterDress2)
            app3.bridesmaidDirectory = parameterDress2;
            app3.Image2.ImageSource = "Bridesmaid\" + app3.bridesmaidDirectory(1).name
            
            y = split(app3.bridesmaidDirectory(1).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app3.Label.Text = "price: " + "$" +k 


            app3.Image2_2.ImageSource = "Bridesmaid\" + app3.bridesmaidDirectory(2).name

              y = split(app3.bridesmaidDirectory(2).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app3.Label2.Text = "price: " + "$" +k 


            app3.Image2_3.ImageSource = "Bridesmaid\" + app3.bridesmaidDirectory(3).name

              y = split(app3.bridesmaidDirectory(3).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app3.Label3.Text = "price: " + "$" +k 

        end 
    end 


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            disp(app.bridesmaidDirectory)
        end

        % Button pushed function: RefreshOptionsButton
        function RefreshOptionsButtonPushed(app, event)
             if app.cnt3 < 10
          app.Image2.ImageSource = "Bridesmaid\"+ app.bridesmaidDirectory(app.cnt3).name

             y = split(app.bridesmaidDirectory(app.cnt3).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app.Label.Text = "price: " + "$" +k

          app.cnt3 = app.cnt3 +1 
          app.Image2_2.ImageSource = "Bridesmaid\"+app.bridesmaidDirectory(app.cnt3).name

           y = split(app.bridesmaidDirectory(app.cnt3).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app.Label2.Text = "price: " + "$" +k

          app.cnt3 = app.cnt3 +1 
          app.Image2_3.ImageSource = "Bridesmaid\"+app.bridesmaidDirectory(app.cnt3).name

           y = split(app.bridesmaidDirectory(app.cnt3).name, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app.Label3.Text = "price: " + "$" +k

          app.cnt3 = app.cnt3 +1 

          end 
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
             CPSProject_App7.setColorDressDirectory2(CPSProject_App7,app.Image2.ImageSource)
            app.delete
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
             CPSProject_App7.setColorDressDirectory2(CPSProject_App7,app.Image2_2.ImageSource)
            app.delete
        end

        % Button pushed function: SelectButton_3
        function SelectButton_3Pushed(app, event)
             CPSProject_App7.setColorDressDirectory2(CPSProject_App7,app.Image2_3.ImageSource)
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BridesmaidOptionsShow.jpg');
            app.BridesmaidOptionsLabel = uilabel(app.UIFigure);
            app.BridesmaidOptionsLabel.Text = 'Bridesmaid Options';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_3 = uiimage(app.UIFigure);
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SelectButton_2 = uibutton(app.UIFigure, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.SelectButton_3 = uibutton(app.UIFigure, 'push');
            app.SelectButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_3Pushed, true);
            app.SelectButton_3.Text = 'Select';
            app.RefreshOptionsButton = uibutton(app.UIFigure, 'push');
            app.RefreshOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshOptionsButtonPushed, true);
            app.RefreshOptionsButton.Text = 'Refresh Options';
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CPSProject_App6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end