classdef CPSProject_App7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SelectButton                matlab.ui.control.Button
        ColorDropDown               matlab.ui.control.DropDown
        ColorDropDownLabel          matlab.ui.control.Label
        Image3                      matlab.ui.control.Image
        AddThisDressToCartButton    matlab.ui.control.Button
        DressInDifferentColorLabel  matlab.ui.control.Label
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = public)
        ColorDressDirectory2
        BridesmaidShoppingCart
        I_new1
        yes = 0
        app4
        image2
       
    end


    methods (Access = public, Static)
        
        function setColorDressDirectory2(app4, image2)
            app4.ColorDressDirectory2 = image2;
            app4.Image2.ImageSource = app4.ColorDressDirectory2
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SliderValueChanged(app, event)
           %{
            value = app.Slider.Value;
            
            I2 = app.Image2.ImageSource;
            I4 = imread(I2);
           
               
        isRed1 = I4(:,:,1)< 70 & I4(:,:,2)< 70 & I4(:,:,3) < 70;
        isRedArray1 = repmat(isRed1,1,1,3); 
        I_temp1 = I4; 
        I_temp1(isRedArray1) = 0;
        app.I_new1 = I4; 
           
        app.I_new1(isRedArray1) = app.I_new1(isRedArray1) + value; 
        app.Image2.ImageSource = app.I_new1;
     
           %}
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
        %{
            if app.yes == 1
            CPSProject_App8.setBridesmaidShoppingCart(CPSProject_App8, app.Image2.ImageSource)
             
            app.delete

            end 
        %}

            
             app.yes = 1; 

            CPSProject_App8.setyesfunction2(CPSProject_App8, app.yes)
           
            app.delete

        end

        % Button pushed function: AddThisDressToCartButton
        function AddThisDressToCartButtonPushed(app, event)
          %  app.yes = 1;
          a = dir('ShoppingCart\bridesmaid\*.jpg')
          a = "ShoppingCart\bridesmaid\" +  a(1).name
           delete(a)
          copyfile(app.ColorDressDirectory2, "ShoppingCart\bridesmaid")
          
          %x = 'ShoppingCart\'+app.ColorDressDirectory2
         
         
        end

        % Callback function
        function InCartEditFieldValueChanged(app, event)
           % value = app.yes;
            
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;
   
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            
         app.Image2.ImageSource = app.ColorDressDirectory2;
            Color = app.ColorDropDown.Value;
           
            I2 = app.Image2.ImageSource;
            
            I4 = imread(I2);
 
            if Color == "Blue"
                th = 20;
[x,y,~]=size(I4);
for ii=1:x
    for jj=1:y
        if((I4(ii,jj,1)-th)< I4(ii,jj,2)&& (I4(ii,jj,1)-th)<I4(ii,jj,3))
            I4(ii,jj,1) = I4(ii,jj,1) + 150;I4(ii,jj,2) = I4(ii,jj,2) + 150;I4(ii,jj,3) = I4(ii,jj,1) + 250;
            
        end
    end
end 
            end 

if Color == "Pink"
                th = 20;
[x,y,~]=size(I4);
for ii=1:x
    for jj=1:y
        if((I4(ii,jj,1)-th)< I4(ii,jj,2)&& (I4(ii,jj,1)-th)<I4(ii,jj,3))
            I4(ii,jj,1) = I4(ii,jj,1)+ 250;I4(ii,jj,2) = I4(ii,jj,2) + 150;I4(ii,jj,3) = I4(ii,jj,3) +250;
            
        end
    end
end 
end 

if Color == "Lavender"
                th = 20;
[x,y,~]=size(I4);
for ii=1:x
    for jj=1:y
        if((I4(ii,jj,1)-th)< I4(ii,jj,2)&& (I4(ii,jj,1)-th)<I4(ii,jj,3))
            I4(ii,jj,1) = I4(ii,jj,1) + 200;I4(ii,jj,2) = I4(ii,jj,2) + 150;I4(ii,jj,3) = I4(ii,jj,3) + 200;
            
        end
    end
end 
end 

if Color == "Yellow"
                th = 20;
[x,y,~]=size(I4);
for ii=1:x
    for jj=1:y
        if((I4(ii,jj,1)-th)< I4(ii,jj,2)&& (I4(ii,jj,1)-th)<I4(ii,jj,3))
            I4(ii,jj,1) = I4(ii,jj,1) + 250;I4(ii,jj,2) = I4(ii,jj,2) + 210;I4(ii,jj,3) = I4(ii,jj,3) + 150;
            
        end
    end
end 
end 

if Color == "Green"
                th = 20;
[x,y,~]=size(I4);
for ii=1:x
    for jj=1:y
        if((I4(ii,jj,1)-th)< I4(ii,jj,2)&& (I4(ii,jj,1)-th)<I4(ii,jj,3))
            I4(ii,jj,1) = I4(ii,jj,1) + 120;I4(ii,jj,2) = I4(ii,jj,2) + 200;I4(ii,jj,3) = I4(ii,jj,3) +110;
            
        end
    end
end 
end 

if Color == "Original"

   I4 = app.ColorDressDirectory2;
end 


            app.Image2.ImageSource = I4;
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Flower Floral Wedding Invitation.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.DressInDifferentColorLabel = uilabel(app.UIFigure);
            app.DressInDifferentColorLabel.Text = 'Dress In Different Color';
            app.AddThisDressToCartButton = uibutton(app.UIFigure, 'push');
            app.AddThisDressToCartButton.ButtonPushedFcn = createCallbackFcn(app, @AddThisDressToCartButtonPushed, true);
            app.AddThisDressToCartButton.Text = 'Add This Dress To Cart';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'ShoppingCartIconNew.png');
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'Blue', 'Original', 'Pink', 'Lavender', 'Yellow', 'Green'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Value = 'Blue';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CPSProject_App7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end