classdef CPSProject_App8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        SwapButton_5           matlab.ui.control.Button
        Label3                 matlab.ui.control.Label
        Image3                 matlab.ui.control.Image
        Image11                matlab.ui.control.Image
        Tab2                   matlab.ui.container.Tab
        SwapButton_4           matlab.ui.control.Button
        Label4                 matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        Image10                matlab.ui.control.Image
        Tab3                   matlab.ui.container.Tab
        SwapButton_3           matlab.ui.control.Button
        heyLabel               matlab.ui.control.Label
        Image4                 matlab.ui.control.Image
        Image5                 matlab.ui.control.Image
        Tab4                   matlab.ui.container.Tab
        SwapButton_2           matlab.ui.control.Button
        Label2                 matlab.ui.control.Label
        Image6                 matlab.ui.control.Image
        Image8                 matlab.ui.control.Image
        Tab5                   matlab.ui.container.Tab
        SwapButton             matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        Image7                 matlab.ui.control.Image
        Image9                 matlab.ui.control.Image
        Tab6                   matlab.ui.container.Tab
        Label5                 matlab.ui.control.Label
        TotalLabel             matlab.ui.control.Label
        Image12                matlab.ui.control.Image
        YourShoppingCartLabel  matlab.ui.control.Label
        GoToMainButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        %Property % Description
        %{
        ShoppingCart = "";
        ShoppingCart2 = "";
        JewelryCart = "";
        HairCart = "";
        ShoesCart = "";
        BrideCount = 0;
        BridesmaidCount = 0;
        %}

        brideCheck
        bridesmaidCheck
        jewelryCheck
        hairCheck
        shoesCheck
    end

    %{
    methods (Access = public, Static)
        
        function setBridesmaidShoppingCart(app8, cart)
           
            app8.ShoppingCart = cart;

            app8.Image.ImageSource = app8.ShoppingCart
        
       
        end
    end
    
    
    methods (Access = public, Static)
        
        function setBrideShoppingCart(app82, cart2)
           
            app82.ShoppingCart2 = cart2
            app82.Image3.ImageSource = app82.ShoppingCart2

             y7 = split(app82.ShoppingCart2.name, '_')
            x7 = split(y7(end), '.')
            k7 = str2double(x7(1,1))
            app82.Label3.Text = "price: " + "$" + k7
        
        end
    end
%{
methods (Access = public, Static)
        
        function setBridesmaidShoppingCart3(app83, cart3)
          
            app83.ShoppingCart3 = cart3;
            y = split(app83.ShoppingCart3, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app83.heyLabel.Text = "price: " + "$" + k
            app83.Image4.ImageSource = app83.ShoppingCart3
        
        end
end

methods (Access = public, Static)
        
        function setBridesmaidShoppingCart4(app84, cart4)
          
            app84.ShoppingCart4 = cart4;
            y1 = split(app84.ShoppingCart4, '_')
            x1 = split(y1(end), '.')
            k1 = str2double(x1(1,1))
            app84.Label2.Text = "price: " + "$" + k1
            app84.Image6.ImageSource = app84.ShoppingCart4
        
        end
end 

methods (Access = public, Static)
        
        function setBridesmaidShoppingCart5(app85, cart5)
          
            app85.ShoppingCart5 = cart5;
            y3 = split(app85.ShoppingCart5, '_')
            x3 = split(y1(end), '.')
            k3 = str2double(x1(1,1))
            app85.Label.Text = "price: " + "$" + k3
            app85.Image7.ImageSource = app85.ShoppingCart5
        
        end
end 
%}

methods (Access = public, Static)
        
        function setBridesmaidShoppingCart3(app85, Jewelry, Hair, Shoes)
         
            app85.JewelryCart = Jewelry;
            y3 = split(app85.JewelryCart, '_')
            x3 = split(y3(end), '.')
            k3 = str2double(x3(1,1))
            app85.heyLabel.Text = "price: " + "$" + k3
            app85.Image4.ImageSource = app85.JewelryCart

            app85.HairCart = Hair;
            y1 = split(app85.HairCart, '_')
            x1 = split(y1(end), '.')
            k1 = str2double(x1(1,1))
            app85.Label2.Text = "price: " + "$" + k1
            app85.Image6.ImageSource = app85.HairCart

            app85.ShoesCart = Shoes;
            y = split(app85.ShoesCart, '_')
            x = split(y(end), '.')
            k = str2double(x(1,1))
            app85.Label.Text = "price: " + "$" + k
            app85.Image7.ImageSource = app85.ShoesCart

        
        end
end 
   
%}
     

methods (Access = public, Static)
        
    function setyesfunction(app, check)
        app.brideCheck = check
    end 
end 
            
 methods (Access = public, Static)
        
    function setyesfunction2(app, check)
        app.bridesmaidCheck = check;
    end 
 end     

 methods (Access = public, Static)
        
     function setyesfunction3(app, check)
        app.jewelryCheck = check;
    end 
 end     

 methods (Access = public, Static)
        
     function setyesfunction4(app, check)
        app.hairCheck = check;
    end 
 end     

 methods (Access = public, Static)
        
     function setyesfunction5(app, check)
        app.shoesCheck = check;
    end 
 end     


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


               a = dir('ShoppingCart\Bride\*.jpg')
              
              app.Image3.ImageSource = "ShoppingCart\Bride\" + a(1).name

               y = split(a(1).name, '_')
               x = split(y(end), '.')
               k = str2double(x(1,1))
               app.Label3.Text = "price: " + "$" + k


              a1 = dir('ShoppingCart\bridesmaid\*.jpg')
              
              app.Image.ImageSource = "ShoppingCart\bridesmaid\" + a1(1).name

               y1 = split(a1(1).name, '_')
               x1 = split(y1(end), '.')
               k1 = str2double(x1(1,1))
               app.Label4.Text = "price: " + "$" + k1
               k = k+ k1;


              a2 = dir('ShoppingCart\Jewelry\*.jpg')
              
              app.Image4.ImageSource = "ShoppingCart\Jewelry\" + a2(1).name

               y2 = split(a2(1).name, '_')
               x2 = split(y2(end), '.')
               k2 = str2double(x2(1,1))
               app.heyLabel.Text = "price: " + "$" + k2

                k = k + k2;
               
               
              a3 = dir('ShoppingCart\Hair\*.jpg')
              
              app.Image6.ImageSource = "ShoppingCart\Hair\" + a3(1).name

               y3 = split(a3(1).name, '_')
               x3 = split(y3(end), '.')
               k3 = str2double(x3(1,1))
               app.Label2.Text = "price: " + "$" + k3

               k = k + k3;


               a4 = dir('ShoppingCart\Shoes\*.jpg')
              
              app.Image7.ImageSource = "ShoppingCart\Shoes\" + a4(1).name

               y4 = split(a4(1).name, '_')
               x4 = split(y4(end), '.')
               k4 = str2double(x4(1,1))
               app.Label.Text = "price: " + "$" + k4

               k = k + k4;

               app.Label5.Text = "$" + k;


        end

        % Button pushed function: GoToMainButton
        function GoToMainButtonPushed(app, event)
            CPSProject_App1_2
            
       app.delete
             
        end

        % Button pushed function: SwapButton_5
        function SwapButton_5Pushed(app, event)
            CPSProject_App2
            app.delete
        end

        % Button pushed function: SwapButton_4
        function SwapButton_4Pushed(app, event)
            CPSProject_App5
            app.delete
        end

        % Button pushed function: SwapButton_3
        function SwapButton_3Pushed(app, event)
            CPSProject_App9
            app.delete
        end

        % Button pushed function: SwapButton_2
        function SwapButton_2Pushed(app, event)
             CPSProject_App9
            app.delete
        end

        % Button pushed function: SwapButton
        function SwapButtonPushed(app, event)
             CPSProject_App9
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GoToMainButton = uibutton(app.UIFigure, 'push');
            app.GoToMainButton.ButtonPushedFcn = createCallbackFcn(app, @GoToMainButtonPushed, true);
            app.GoToMainButton.Text = 'Go To Main';
            app.YourShoppingCartLabel = uilabel(app.UIFigure);
            app.YourShoppingCartLabel.Text = 'Your ShoppingCart';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Image11 = uiimage(app.Tab);
            app.Image11.ImageSource = fullfile(pathToMLAPP, 'ShoppingCartBackground.png');
            app.Image3 = uiimage(app.Tab);
            app.Label3 = uilabel(app.Tab);
            app.Label3.Text = 'Label3';
            app.SwapButton_5 = uibutton(app.Tab, 'push');
            app.SwapButton_5.ButtonPushedFcn = createCallbackFcn(app, @SwapButton_5Pushed, true);
            app.SwapButton_5.Text = 'Swap';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Image10 = uiimage(app.Tab2);
            app.Image10.ImageSource = fullfile(pathToMLAPP, 'Beige Simple Floral  Wedding Invitation Banner (60 × 36 in) (60 × 45 in).png');
            app.Image = uiimage(app.Tab2);
            app.Label4 = uilabel(app.Tab2);
            app.Label4.Text = 'Label4';
            app.SwapButton_4 = uibutton(app.Tab2, 'push');
            app.SwapButton_4.ButtonPushedFcn = createCallbackFcn(app, @SwapButton_4Pushed, true);
            app.SwapButton_4.Text = 'Swap';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.Image5 = uiimage(app.Tab3);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'JewelryShopping.png');
            app.Image4 = uiimage(app.Tab3);
            app.heyLabel = uilabel(app.Tab3);
            app.heyLabel.Text = 'hey';
            app.SwapButton_3 = uibutton(app.Tab3, 'push');
            app.SwapButton_3.ButtonPushedFcn = createCallbackFcn(app, @SwapButton_3Pushed, true);
            app.SwapButton_3.Text = 'Swap';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.Image8 = uiimage(app.Tab4);
            app.Image8.ImageSource = fullfile(pathToMLAPP, '1.png');
            app.Image6 = uiimage(app.Tab4);
            app.Label2 = uilabel(app.Tab4);
            app.Label2.Text = 'Label2';
            app.SwapButton_2 = uibutton(app.Tab4, 'push');
            app.SwapButton_2.ButtonPushedFcn = createCallbackFcn(app, @SwapButton_2Pushed, true);
            app.SwapButton_2.Text = 'Swap';
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.Image9 = uiimage(app.Tab5);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'ShoesShopping.png');
            app.Image7 = uiimage(app.Tab5);
            app.Label = uilabel(app.Tab5);
            app.SwapButton = uibutton(app.Tab5, 'push');
            app.SwapButton.ButtonPushedFcn = createCallbackFcn(app, @SwapButtonPushed, true);
            app.SwapButton.Text = 'Swap';
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'Tab6';
            app.Image12 = uiimage(app.Tab6);
            app.Image12.ImageSource = fullfile(pathToMLAPP, 'BridesMaidOptions (24 × 24 in).png');
            app.TotalLabel = uilabel(app.Tab6);
            app.TotalLabel.Text = 'Total';
            app.Label5 = uilabel(app.Tab6);
            app.Label5.Text = 'Label5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CPSProject_App8

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end