classdef CPSProject_App9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ShoesDropDown                 matlab.ui.control.DropDown
        ShoesDropDownLabel            matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        JewelryDropDown               matlab.ui.control.DropDown
        JewelryDropDownLabel          matlab.ui.control.Label
        HairAccessoriesDropDown       matlab.ui.control.DropDown
        HairAccessoriesDropDownLabel  matlab.ui.control.Label
        AccessoriesLabel              matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = public)
        Property % Description
        AccessoryDirectory
        AccessoryDirectory2
        AccessoryDirectory3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: HairAccessoriesDropDown
        function HairAccessoriesDropDownValueChanged(app, event)
            value = app.HairAccessoriesDropDown.Value;
            
        end

        % Value changed function: JewelryDropDown
        function JewelryDropDownValueChanged(app, event)
            value = app.JewelryDropDown.Value;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Jewelry = app.JewelryDropDown.Value;
            Hair = app.HairAccessoriesDropDown.Value;
            Shoes = app.ShoesDropDown.Value;

         
         searchFile = "Accessories\*_"+Jewelry+ "*.jpg";
         searchFile2 = "Accessories\*_"+Hair+ "*.jpg";
         searchFile3 = "Accessories\*_"+Shoes+ "*.jpg";
         
         %if DressType == "mermaid" && Color == "White" && Theme == "Glamorous"
             %app.dressDirectory = dir('*_Mermaid_Glamorous_white*.jpg')
              app.AccessoryDirectory = dir(searchFile);
              app.AccessoryDirectory2 = dir(searchFile2);
              app.AccessoryDirectory3 = dir(searchFile3);
             
             %CPSProject_App3
             CPSProject_App10.setAccessoryDirectory(CPSProject_App10, app.AccessoryDirectory, app.AccessoryDirectory2,app.AccessoryDirectory3)
             
            app.delete
        end

        % Value changed function: ShoesDropDown
        function ShoesDropDownValueChanged(app, event)
            value = app.ShoesDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'AccessoriesOptions.png');
            app.AccessoriesLabel = uilabel(app.UIFigure);
            app.AccessoriesLabel.Text = 'Accessories';
            app.HairAccessoriesDropDownLabel = uilabel(app.UIFigure);
            app.HairAccessoriesDropDownLabel.Text = 'Hair Accessories';
            app.HairAccessoriesDropDown = uidropdown(app.UIFigure);
            app.HairAccessoriesDropDown.Items = {'Bow', 'Tiara', 'Veil', 'Headband'};
            app.HairAccessoriesDropDown.ValueChangedFcn = createCallbackFcn(app, @HairAccessoriesDropDownValueChanged, true);
            app.HairAccessoriesDropDown.Value = 'Bow';
            app.JewelryDropDownLabel = uilabel(app.UIFigure);
            app.JewelryDropDownLabel.Text = 'Jewelry';
            app.JewelryDropDown = uidropdown(app.UIFigure);
            app.JewelryDropDown.Items = {'Earrings', 'Flowers', 'Necklace', 'Bracelet', 'Sets'};
            app.JewelryDropDown.ValueChangedFcn = createCallbackFcn(app, @JewelryDropDownValueChanged, true);
            app.JewelryDropDown.Value = 'Earrings';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ShoesDropDownLabel = uilabel(app.UIFigure);
            app.ShoesDropDownLabel.Text = 'Shoes';
            app.ShoesDropDown = uidropdown(app.UIFigure);
            app.ShoesDropDown.Items = {'Wedges', 'Stilettos', 'Flats', 'Block'};
            app.ShoesDropDown.ValueChangedFcn = createCallbackFcn(app, @ShoesDropDownValueChanged, true);
            app.ShoesDropDown.Value = 'Wedges';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CPSProject_App9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end